classdef strc_calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ModeShapesCalculatorUIFigure    matlab.ui.Figure
        MuhammadHaseebBScCivilEngineeringmhaseebmohalgmailcomLabel_3  matlab.ui.control.Label
        MuhammadHaseebBScCivilEngineeringmhaseebmohalgmailcomLabel  matlab.ui.control.Label
        Image_2                         matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        ResetButton                     matlab.ui.control.Button
        SampleDataButton                matlab.ui.control.Button
        Columns                         matlab.ui.control.NumericEditField
        ColumnsEditFieldLabel           matlab.ui.control.Label
        Rows                            matlab.ui.control.NumericEditField
        RowsEditFieldLabel              matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ModelStiffnessMatrixLabel       matlab.ui.control.Label
        modelstiffnessbtn               matlab.ui.control.Button
        ModelMassMatrixLabel            matlab.ui.control.Label
        modelmassbtn                    matlab.ui.control.Button
        CheckFrequencyradsTimePeriodsLabel  matlab.ui.control.Label
        Tnfbtn                          matlab.ui.control.Button
        modeshapebtn                    matlab.ui.control.Button
        CheckModeShapesLabel            matlab.ui.control.Label
        GetyourvaluesLabel              matlab.ui.control.Label
        DoneLabel_2                     matlab.ui.control.Label
        DoneLabel                       matlab.ui.control.Label
        Kvaluebtn                       matlab.ui.control.Button
        Mvaluebtn                       matlab.ui.control.Button
        DefineStiffnessKMatrixLabel     matlab.ui.control.Label
        DefineMassMMatrixLabel          matlab.ui.control.Label
        ModeShapesCalculatorLabel       matlab.ui.control.Label
        dropdown                        matlab.ui.control.DropDown
        SelectCalculationDropDownLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        matrix1
        matrix2
        heightdata2
        axes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Clicked callback: dropdown
        function dropdownClicked(app, event)
            item = event.InteractionInformation.Item;
        end

        % Button pushed function: Mvaluebtn
        function MvaluebtnButtonPushed(app, event)

            if app.Rows.Value==0 || app.Columns.Value==0
                app.Rows.Value=2;
                app.Columns.Value=2
            end
            if app.Rows.Value ~= app.Columns.Value
                warndlg('Please column and rows should be in square format i.e Mtrix: 2x2 or 3x3...NxN etc','Error')
                return;
            end
            rows = app.Rows.Value;
            columns = app.Columns.Value;
            cellwidth = 100;
            cellheight = 30;
            spacing = 10;
            sss = get(0,"ScreenSize")
            wfactor = sss(3)*0.6
            hfactor = sss(4)*0.6
            a = uifigure("Name","Enter Mass (M) Values","Position",[50 50 sss(3)-wfactor sss(4)-hfactor],Scrollable="on");

            pwdh = max(300, spacing+columns*1.1*(spacing+cellwidth));
            pheth = max(200, spacing+rows*(spacing+cellheight));
            b = uipanel(a,"Position",[65 65 pwdh pheth]);
            for r=1:rows
                for c=1:columns
                    x1 = spacing + (c-1)*(spacing + cellwidth);
                    y1 = pheth - (r*(spacing + cellheight));
                    x{r,c} = uicontrol(b,"Style","edit","Position",[x1 y1 cellwidth cellheight]);
                end
            end
            uicontrol(a,"Style","pushbutton","String","Done","Position",[60+pheth/3 20 60+pheth/3 30],"Callback",@(src, event) buttonclick(x));
            function buttonclick(x)
                for r=1:size(x,1)
                    for c=1:size(x,2)
                        matrix(r,c) = str2double(x{r, c}.String);
                        if isempty(matrix(r,c)) || isnan(matrix(r,c))
                            warndlg('Some Value is Missing, Check again','Error')
                            return;
                        else
                            app.matrix1 = matrix;
                        end
                    end
                end
                app.DoneLabel.Visible="on";
                close(a);
            end
        end

        % Value changed function: Rows
        function RowsValueChanged(app, event)
            value = app.Rows.Value;
        end

        % Value changed function: Columns
        function ColumnsValueChanged(app, event)
            value = app.Columns.Value;
        end

        % Button pushed function: Kvaluebtn
        function KvaluebtnButtonPushed(app, event)
            if app.Rows.Value==0 || app.Columns.Value==0
                app.Rows.Value=2;
                app.Columns.Value=2
            end
            if app.Rows.Value ~= app.Columns.Value
                warndlg('Please column and rows should be in square format i.e Mtrix: 2x2 or 3x3...NxN etc','Error')
                return;
            end
            rows = app.Rows.Value;
            columns = app.Columns.Value;
            cellwidth = 100;
            cellheight = 30;
            spacing = 10;
            sss = get(0,"ScreenSize")
            wfactor = sss(3)*0.6
            hfactor = sss(4)*0.6
            a = uifigure("Name","Enter Stiffness (K) Values","Position",[50 50 sss(3)-wfactor sss(4)-hfactor],Scrollable="on");
            pwdh = max(300, spacing+columns*1.1*(spacing+cellwidth));
            pheth = max(200, spacing+rows*(spacing+cellheight));
            b = uipanel(a,"Position",[65 65 pwdh pheth]);
            for r=1:rows
                for c=1:columns
                    x1 = spacing + (c-1)*(spacing + cellwidth);
                    y1 = pheth - (r*(spacing + cellheight));
                    x{r,c} = uicontrol(b, "Style","edit","Position",[x1 y1 cellwidth cellheight]);
                end
            end
            uicontrol(a,"Style","pushbutton","String","Done","Position",[60+pheth/3 20 60+pheth/3 30],"Callback",@(src, event) buttonclick(x));
            function buttonclick(x)
                for r=1:size(x,1)
                    for c=1:size(x,2)
                        matrix(r,c) = str2double(x{r, c}.String);
                        if isempty(matrix(r,c)) || isnan(matrix(r,c))
                            warndlg('Some Value is Missing, Check again','Error')
                            return;
                        else
                            app.matrix2 = matrix;
                        end
                    end
                end
                app.DoneLabel_2.Visible="on";
                close(a);
            end
        end

        % Button pushed function: Tnfbtn
        function TnfbtnButtonPushed(app, event)

            if any(isempty(app.matrix1(:))) || any(isempty(app.matrix2(:)))
                warndlg('Please enter Values in M & K matrix or click on sample data', 'Error');
                return;
            end
            a = uifigure("Name",'Frequency & Time Period',"Position",[20 20 380 500],Scrollable="on",Resize="on");

            b = uipanel(a,"Position",[22 22 380 436]);

            [v,w1] = eig(app.matrix2,app.matrix1);
            w = sqrt(w1);
            t = (2*pi)./w;
            [r1,c1] = size(w);
            frequency = [];
            time = [];
            n_frequency = [];
            for r=1:r1
                for c=1:c1
                    frequency = [frequency;w(r, c)];
                    time = [time;t(r, c)]
                    n_frequency = [n_frequency;0.5*(t(r,c))]
                end
            end
            Datad = [frequency,time,n_frequency];
            er = uitable(b,"Data",Datad,"ColumnName",{'Circular Frequency ω (rad/s)','Time Period T (s)','Natural Frquency fn (s)'},"ColumnWidth",'fit',Position=[20 20 330 400]);
        end

        % Button pushed function: modeshapebtn
        function modeshapebtnButtonPushed(app, event)

            if any(isempty(app.matrix1(:))) || any(isempty(app.matrix2(:)))
                warndlg('Please enter Values in M & K matrix or click on sample data', 'Error');
                return;
            end
            a = uifigure(Scrollable="on");
            b = uicontrol(a,"Style","pushbutton","String",'Check Mode values',"BackgroundColor",'0.47,0.67,0.19',"Position",[20 395 210 20],"Callback",@(src,event) modevaluebutton());
            b1 = uicontrol(a,"Style","pushbutton","String",'Click to put Height Values for Mode Shapes','BackgroundColor','1.00,0.84,0.45',"Position",[20 370 210 20],"Callback",@(src,event) heightvalues());
            b1 = uicontrol(a,"Style","text","String",'If no height values are available, assign random values such that all values have an equal difference of 5 or any number (e.g., 5, 10, 15, 20,.... etc.).','BackgroundColor','1.00,1.00,1.00',"Position",[270 370 260 40],HorizontalAlignment="left");
            panel = uipanel(a,"Position",[20 20 5+350*size(app.matrix1,1) 340])
            function heightvalues
                [vv,w2] = eig(app.matrix2,app.matrix1);
                matrixplot = vv;
                hfig = uifigure(Scrollable="on",Position=[20 150 250 50+50*size(matrixplot,1)]);
                for yy=1:size(matrixplot,1)
                    alit{yy} = uicontrol(hfig,"Style","text","String",sprintf("Height of Floor # %d", yy),"Position",[20 50+50*(yy-1) 100 20]);
                    alit{yy} = uicontrol(hfig,"Style","edit","Position",[130 50+50*(yy-1) 60 20]);
                end
                uicontrol(hfig,"Style","pushbutton","String",'Ok','Position',[20 15 50 20],'Callback',@(src,event) okheight);
                function okheight
                    donetext = uicontrol(a,"Style","text","String",'Done','Position',[230 370 30 14],"ForegroundColor",'0.39,0.83,0.07',Visible='on');
                    heightdata = 0;
                    for uu=1:size(matrixplot,1)
                        heightdata1 = str2double(alit{uu}.String);
                        heightdata = [heightdata;heightdata1];
                        app.heightdata2 = heightdata
                        if isempty(heightdata1) || isnan(heightdata1)
                            warndlg('Something missing','Error')
                            return;
                        end
                        if uu==size(matrixplot,1)
                            delete(panel);
                            panel = uipanel(a,"Position",[20 20 5+350*size(app.matrix1,1) 340])
                        end
                    end
                    [vv,w2] = eig(app.matrix2,app.matrix1);
                    matrixplot = vv;
                    for row1=1:size(matrixplot,1);
                        for col1=1:size(matrixplot,2);
                            dmatrix(row1,col1) = matrixplot(row1,col1)/max(matrixplot(1,col1),[],1);
                        end
                    end
                    for gg=1:size(matrixplot,2)
                        ax = uiaxes(panel,"Position",[5+350*(gg-1) 10 300 320],BoxStyle="full")
                        app.axes = ax;

                        ax.XAxisLocation = "origin";

                        plot(ax,[0; flipud(dmatrix(:, gg))], app.heightdata2, 'k-',[0, 0], [min(app.heightdata2), max(app.heightdata2)], 'r-', 'LineWidth', 1);

                        % plot(ax,[0;flipud(dmatrix(:,gg))],heightdata,[0, 0], [min(heightdata), max(heightdata)], 'r-', 'LineWidth', 1);
                    end

                    close(hfig);
                end
            end
            function modevaluebutton()
                [v,w1] = eig(app.matrix2,app.matrix1);
                matrix = v;
                for row1=1:size(matrix,1);
                    for col1=1:size(matrix,2);
                        dmatrix(row1,col1) = matrix(row1,col1)/max(matrix(1,col1),[],1);
                    end
                end
                figure;
                hold on;
                axis off;
                for row = 0:size(matrix, 1)
                    plot([0.5, size(matrix, 2) + 0.5], [row + 0.5, row + 0.5], 'k');
                end
                for col = 0:size(matrix, 2)
                    plot([col + 0.5, col + 0.5], [0.5, size(matrix, 1) + 0.5], 'k');
                end
                for row = 1:size(matrix, 1)
                    for col = 1:size(matrix, 2)
                        text(col, row, num2str(dmatrix(row, col)),'HorizontalAlignment', 'center','VerticalAlignment', 'middle','FontSize', 12);
                    end
                end
                disableDefaultInteractivity(gca);
                xlim([0.5, size(matrix, 2) + 0.5]);
                ylim([0.5, size(matrix, 1) + 0.5]);
                set(gca, 'YDir', 'reverse');
                title("Mode Shapes Values for " + size(matrix,1) + " X "  + size(matrix,2) + " Matrix");
            end
        end

        % Button pushed function: modelmassbtn
        function modelmassbtnButtonPushed(app, event)

            if any(isempty(app.matrix1(:))) || any(isempty(app.matrix2(:)))
                warndlg('Please enter Values in M & K matrix or click on sample data', 'Error');
                return;
            end
            [v,w1] = eig(app.matrix2,app.matrix1);
            phi_matrix = v;
            mass_matrix = app.matrix1;
            phi_transpose = phi_matrix'
            model_mass = phi_transpose * mass_matrix * phi_matrix
            figure;
            hold on;
            axis off;
            for row = 0:size(phi_matrix, 1)
                plot([0.5, size(phi_matrix, 2) + 0.5], [row + 0.5, row + 0.5], 'k');
            end
            for col = 0:size(phi_matrix, 2)
                plot([col + 0.5, col + 0.5], [0.5, size(phi_matrix, 1) + 0.5], 'k');
            end
            for row = 1:size(phi_matrix, 1)
                for col = 1:size(phi_matrix, 2)
                    text(col, row, num2str(model_mass(row, col)),'HorizontalAlignment', 'center','VerticalAlignment', 'middle','FontSize', 12);
                end
            end
            disableDefaultInteractivity(gca);
            xlim([0.5, size(phi_matrix, 2) + 0.5]);
            ylim([0.5, size(phi_matrix, 1) + 0.5]);
            set(gca, 'YDir', 'reverse');
            title("Model Mass Matrix for " + size(phi_matrix,1) + " X "  + size(phi_matrix,2) + " Matrix");
        end

        % Button pushed function: modelstiffnessbtn
        function modelstiffnessbtnButtonPushed(app, event)

            if any(isempty(app.matrix1(:))) || any(isempty(app.matrix2(:)))
                warndlg('Please enter Values in M & K matrix or click on sample data', 'Error');
                return;
            end
            [v,w1] = eig(app.matrix2,app.matrix1);
            phi_matrix = v;
            stiffness_matrix = app.matrix2;
            phi_transpose = phi_matrix'
            model_stiffness = phi_transpose * stiffness_matrix * phi_matrix
            figure;
            hold on;
            axis off;
            for row = 0:size(phi_matrix, 1)
                plot([0.5, size(phi_matrix, 2) + 0.5], [row + 0.5, row + 0.5], 'k');
            end
            for col = 0:size(phi_matrix, 2)
                plot([col + 0.5, col + 0.5], [0.5, size(phi_matrix, 1) + 0.5], 'k');
            end
            for row = 1:size(phi_matrix, 1)
                for col = 1:size(phi_matrix, 2)
                    text(col, row, num2str(model_stiffness(row, col)),'HorizontalAlignment', 'center','VerticalAlignment', 'middle','FontSize', 12);
                end
            end
            disableDefaultInteractivity(gca);
            xlim([0.5, size(phi_matrix, 2) + 0.5]);
            ylim([0.5, size(phi_matrix, 1) + 0.5]);
            set(gca, 'YDir', 'reverse');
            title("Model Stiffness Matrix for " + size(phi_matrix,1) + " X "  + size(phi_matrix,2) + " Matrix");
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)

            app.matrix1 = {};
            app.matrix2 = {};
            app.Rows.Value = 0;
            app.Columns.Value = 0;
            app.heightdata2 = 0;
            app.DoneLabel.Visible="off";
            app.DoneLabel_2.Visible="off";
        end

        % Button pushed function: SampleDataButton
        function SampleDataButtonPushed(app, event)
            app.matrix1 = [1 0 0;0 1.5 0;0 0 2];
            app.matrix2 = [600 -600 0;-600 1800 -1200;0 -1200 3000];
            app.Rows.Value = 3;
            app.Columns.Value = 3;
            app.heightdata2 = [5 10 15];
            app.DoneLabel.Visible="on";
            app.DoneLabel_2.Visible="on";
        end

        % Size changed function: ModeShapesCalculatorUIFigure
        function ModeShapesCalculatorUIFigureSizeChanged(app, event)


    % Get the current figure size
    figPosition = app.ModeShapesCalculatorUIFigure.Position; % [x, y, width, height]
    figWidth = figPosition(3);
    figHeight = figPosition(4);

    % % Adjust component positions based on figure size
    % app.Button.Position = [10, figHeight - 50, figWidth - 20, 40]; % Example for a button
    % app.EditField.Position = [10, figHeight - 100, figWidth - 20, 30];
    % app.Panel.Position = [10, 10, figWidth - 20, figHeight - 120];


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ModeShapesCalculatorUIFigure = uifigure('Visible', 'off');
            app.ModeShapesCalculatorUIFigure.Name = 'Mode Shapes Calculator ';
            app.ModeShapesCalculatorUIFigure.SizeChangedFcn = createCallbackFcn(app, @ModeShapesCalculatorUIFigureSizeChanged, true);
            app.SelectCalculationDropDownLabel = uilabel(app.ModeShapesCalculatorUIFigure);
            app.SelectCalculationDropDownLabel.Text = 'Select Calculation';
            app.dropdown = uidropdown(app.ModeShapesCalculatorUIFigure);
            app.dropdown.Items = {'Mode Shapes', 'SDOF (Working on it)', 'Option 3', 'Option 4'};
            app.dropdown.ClickedFcn = createCallbackFcn(app, @dropdownClicked, true);
            app.dropdown.Value = 'Mode Shapes';
            app.ModeShapesCalculatorLabel = uilabel(app.ModeShapesCalculatorUIFigure);
            app.ModeShapesCalculatorLabel.Text = 'Mode Shapes Calculator';
            app.DefineMassMMatrixLabel = uilabel(app.ModeShapesCalculatorUIFigure);
            app.DefineMassMMatrixLabel.Text = 'Define Mass (M) Matrix';
            app.DefineStiffnessKMatrixLabel = uilabel(app.ModeShapesCalculatorUIFigure);
            app.DefineStiffnessKMatrixLabel.Text = 'Define Stiffness (K) Matrix';
            app.Mvaluebtn = uibutton(app.ModeShapesCalculatorUIFigure, 'push');
            app.Mvaluebtn.ButtonPushedFcn = createCallbackFcn(app, @MvaluebtnButtonPushed, true);
            app.Mvaluebtn.Text = 'Put M Values';
            app.Kvaluebtn = uibutton(app.ModeShapesCalculatorUIFigure, 'push');
            app.Kvaluebtn.ButtonPushedFcn = createCallbackFcn(app, @KvaluebtnButtonPushed, true);
            app.Kvaluebtn.Text = 'Put K Values';
            app.DoneLabel = uilabel(app.ModeShapesCalculatorUIFigure);
            app.DoneLabel.Visible = 'off';
            app.DoneLabel.Text = 'Done';
            app.DoneLabel_2 = uilabel(app.ModeShapesCalculatorUIFigure);
            app.DoneLabel_2.Visible = 'off';
            app.DoneLabel_2.Text = 'Done';
            app.GetyourvaluesLabel = uilabel(app.ModeShapesCalculatorUIFigure);
            app.GetyourvaluesLabel.Text = 'Get your values...';
            app.CheckModeShapesLabel = uilabel(app.ModeShapesCalculatorUIFigure);
            app.CheckModeShapesLabel.Text = 'Check Mode Shapes';
            app.modeshapebtn = uibutton(app.ModeShapesCalculatorUIFigure, 'push');
            app.modeshapebtn.ButtonPushedFcn = createCallbackFcn(app, @modeshapebtnButtonPushed, true);
            app.modeshapebtn.Text = 'Click here';
            app.Tnfbtn = uibutton(app.ModeShapesCalculatorUIFigure, 'push');
            app.Tnfbtn.ButtonPushedFcn = createCallbackFcn(app, @TnfbtnButtonPushed, true);
            app.Tnfbtn.Text = 'Click here';
            app.CheckFrequencyradsTimePeriodsLabel = uilabel(app.ModeShapesCalculatorUIFigure);
            app.CheckFrequencyradsTimePeriodsLabel.Text = 'Check Frequency(rad/s) & Time Period(s)';
            app.modelmassbtn = uibutton(app.ModeShapesCalculatorUIFigure, 'push');
            app.modelmassbtn.ButtonPushedFcn = createCallbackFcn(app, @modelmassbtnButtonPushed, true);
            app.modelmassbtn.Text = 'Click here';
            app.ModelMassMatrixLabel = uilabel(app.ModeShapesCalculatorUIFigure);
            app.ModelMassMatrixLabel.Text = 'Model Mass Matrix';
            app.modelstiffnessbtn = uibutton(app.ModeShapesCalculatorUIFigure, 'push');
            app.modelstiffnessbtn.ButtonPushedFcn = createCallbackFcn(app, @modelstiffnessbtnButtonPushed, true);
            app.modelstiffnessbtn.Text = 'Click here';
            app.ModelStiffnessMatrixLabel = uilabel(app.ModeShapesCalculatorUIFigure);
            app.ModelStiffnessMatrixLabel.Text = 'Model Stiffness Matrix';
            app.Label = uilabel(app.ModeShapesCalculatorUIFigure);
            app.Label.Text = 'NxN matrix can be solved using this matlab script and it is a user friendly app that can give frequency(w), time period(s), model-mass, and model-stiffness-matrix that can be get correctly without any error. Mode shapes are calcualated with respect to 1.';
            app.RowsEditFieldLabel = uilabel(app.ModeShapesCalculatorUIFigure);
            app.RowsEditFieldLabel.Text = 'Rows';
            app.Rows = uieditfield(app.ModeShapesCalculatorUIFigure, 'numeric');
            app.Rows.ValueChangedFcn = createCallbackFcn(app, @RowsValueChanged, true);
            app.ColumnsEditFieldLabel = uilabel(app.ModeShapesCalculatorUIFigure);
            app.ColumnsEditFieldLabel.Text = 'Columns';
            app.Columns = uieditfield(app.ModeShapesCalculatorUIFigure, 'numeric');
            app.Columns.ValueChangedFcn = createCallbackFcn(app, @ColumnsValueChanged, true);
            app.SampleDataButton = uibutton(app.ModeShapesCalculatorUIFigure, 'push');
            app.SampleDataButton.ButtonPushedFcn = createCallbackFcn(app, @SampleDataButtonPushed, true);
            app.SampleDataButton.Text = 'Sample Data';
            app.ResetButton = uibutton(app.ModeShapesCalculatorUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset ';
            app.Image = uiimage(app.ModeShapesCalculatorUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, '11.PNG');
            app.Image_2 = uiimage(app.ModeShapesCalculatorUIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, '22.PNG');
            app.MuhammadHaseebBScCivilEngineeringmhaseebmohalgmailcomLabel = uilabel(app.ModeShapesCalculatorUIFigure);
            app.MuhammadHaseebBScCivilEngineeringmhaseebmohalgmailcomLabel.Text = 'linkedin.com/in/mhaseebmohal';
            app.MuhammadHaseebBScCivilEngineeringmhaseebmohalgmailcomLabel_3 = uilabel(app.ModeShapesCalculatorUIFigure);
            app.MuhammadHaseebBScCivilEngineeringmhaseebmohalgmailcomLabel_3.Text = 'Muhammad Haseeb    BSc Civil Engineering';
            % Show the figure after all components are created
            app.ModeShapesCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = strc_calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ModeShapesCalculatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ModeShapesCalculatorUIFigure)
        end
    end
end